classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        UIAxes2              matlab.ui.control.UIAxes
        FunctionsPanel       matlab.ui.container.Panel
        SelectImageButton    matlab.ui.control.Button
        SmoothButton         matlab.ui.control.Button
        EdgeDetectButton     matlab.ui.control.Button
        ResetButton          matlab.ui.control.Button
        SharpenButton        matlab.ui.control.Button
        sizeEditFieldLabel   matlab.ui.control.Label
        sizeEditField        matlab.ui.control.NumericEditField
        sigmaEditFieldLabel  matlab.ui.control.Label
        sigmaEditField       matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [fileName, pathName] = uigetfile({'*.jpg';'*.jpeg'}, 'Select Image');
            file = [pathName, fileName];
            global I;
            global G;
            global type;
            
            I = im2double(imread(file));
            if size(I,3) == 3
                type = "rgb";
            else
                type = "grayscale";
            end
            G = I;
            imshow(I,'Parent',app.UIAxes);
            imshow(G,'Parent',app.UIAxes2);
        end

        % Button pushed function: SmoothButton
        function SmoothButtonPushed(app, event)
            global G;
            global type;
            if type == "rgb"
                R_channel = G(:,:,1);
                G_channel = G(:,:,2);
                B_channel = G(:,:,3);
                G_Smooth(:,:,1) = smooth(R_channel, app.sizeEditField.Value, app.sigmaEditField.Value);
                G_Smooth(:,:,2) = smooth(G_channel, app.sizeEditField.Value, app.sigmaEditField.Value);
                G_Smooth(:,:,3) = smooth(B_channel, app.sizeEditField.Value, app.sigmaEditField.Value);
            else
                G_Smooth = smooth(G, app.sizeEditField.Value, app.sigmaEditField.Value);
            end            
            imshow(G_Smooth,'Parent',app.UIAxes2);
            G = G_Smooth;
        end

        % Button pushed function: EdgeDetectButton
        function EdgeDetectButtonPushed(app, event)
            global G;
            global type;
            if type == "rgb"
                R_channel = G(:,:,1);
                G_channel = G(:,:,2);
                B_channel = G(:,:,3);
                G_edges(:,:,1) = edge_detect(R_channel);
                G_edges(:,:,2) = edge_detect(G_channel);
                G_edges(:,:,3) = edge_detect(B_channel);
            else
                G_edges = edge_detect(G);
            end         
            imshow(G_edges,'Parent',app.UIAxes2);
            G = G_edges;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global I;
            global G;
            G = I;
            imshow(I,'Parent',app.UIAxes);
            imshow(G,'Parent',app.UIAxes2);
        end

        % Button pushed function: SharpenButton
        function SharpenButtonPushed(app, event)
            global G;
            global type;
            if type == "rgb"
                R_channel = G(:,:,1);
                G_channel = G(:,:,2);
                B_channel = G(:,:,3);
                G_sharp(:,:,1) = sharpen(R_channel);
                G_sharp(:,:,2) = sharpen(G_channel);
                G_sharp(:,:,3) = sharpen(B_channel);
            else
                G_sharp = sharpen(G);
            end         
            imshow(G_sharp,'Parent',app.UIAxes2);
            G = G_sharp;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.FunctionsPanel = uipanel(app.UIFigure);
            app.FunctionsPanel.Title = 'Functions';
            app.SelectImageButton = uibutton(app.FunctionsPanel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.SmoothButton = uibutton(app.FunctionsPanel, 'push');
            app.SmoothButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothButtonPushed, true);
            app.SmoothButton.Text = 'Smooth';
            app.EdgeDetectButton = uibutton(app.FunctionsPanel, 'push');
            app.EdgeDetectButton.ButtonPushedFcn = createCallbackFcn(app, @EdgeDetectButtonPushed, true);
            app.EdgeDetectButton.Text = {'Edge Detect'; ''};
            app.ResetButton = uibutton(app.FunctionsPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SharpenButton = uibutton(app.FunctionsPanel, 'push');
            app.SharpenButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenButtonPushed, true);
            app.SharpenButton.Text = 'Sharpen';
            app.sizeEditFieldLabel = uilabel(app.FunctionsPanel);
            app.sizeEditFieldLabel.Text = 'size';
            app.sizeEditField = uieditfield(app.FunctionsPanel, 'numeric');
            app.sizeEditField.RoundFractionalValues = 'on';
            app.sizeEditField.Value = 3;
            app.sigmaEditFieldLabel = uilabel(app.FunctionsPanel);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.FunctionsPanel, 'numeric');
            app.sigmaEditField.RoundFractionalValues = 'on';
            app.sigmaEditField.Value = 2;
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end